classdef GUIPendulo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ODEgrButton         matlab.ui.control.Button
        ODEprButton         matlab.ui.control.Button
        WnangulogrButton    matlab.ui.control.Button
        WnangulopqButton    matlab.ui.control.Button
        ValorWnTextArea     matlab.ui.control.TextArea
        WnLabel             matlab.ui.control.Label
        ANGULOGRANDEButton  matlab.ui.control.Button
        ANGULOPEQUEOButton  matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        t
        theta
        tout
        r_theta
        Wn
        ODE
        tiempo
        posicion
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ANGULOPEQUEOButton
        function ANGULOPEQUEOButtonPushed(app, event)
            Datos_pq = readtable('pendulo_1_pq.xlsx');
            app.theta = table2array(Datos_pq(:,7));
            app.t = table2array(Datos_pq(:,1));
            plot(app.UIAxes, app.t, app.theta,"o")
        end

        % Button pushed function: ANGULOGRANDEButton
        function ANGULOGRANDEButtonPushed(app, event)
            Datos_gr = readtable('pendulo_1_gr.xlsx');
            app.theta = table2array(Datos_gr(:,7));
            app.t = table2array(Datos_gr(:,1));
            plot(app.UIAxes, app.t, app.theta,"o")
        end

        % Button pushed function: WnangulopqButton
        function WnangulopqButtonPushed(app, event)
            Wn_pq = -5.98;
            app.ValorWnTextArea.Value = string(Wn_pq);
        end

        % Button pushed function: ODEprButton
        function ODEprButtonPushed(app, event)
            app.ODE = sim("ODEPendulo.slx");
            app.tiempo = app.ODE.tout;
            app.posicion = app.ODE.theta;
            plot(app.UIAxes,app.tiempo,app.posicion,"LineStyle","--")
            hold(app.UIAxes, 'on')
        end

        % Callback function
        function ClearAxisButtonPushed(app, event)

        end

        % Button pushed function: ODEgrButton
        function ODEgrButtonPushed(app, event)
            app.ODE = sim("ODEPendulo_gr.slx");
            app.tiempo = app.ODE.tout;
            app.posicion = app.ODE.theta;
            plot(app.UIAxes,app.tiempo,app.posicion,"LineStyle","--")
            hold(app.UIAxes, 'on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ANGULOPEQUEOButton = uibutton(app.UIFigure, 'push');
            app.ANGULOPEQUEOButton.ButtonPushedFcn = createCallbackFcn(app, @ANGULOPEQUEOButtonPushed, true);
            app.ANGULOPEQUEOButton.Text = 'ANGULO PEQUEÑO';
            app.ANGULOGRANDEButton = uibutton(app.UIFigure, 'push');
            app.ANGULOGRANDEButton.ButtonPushedFcn = createCallbackFcn(app, @ANGULOGRANDEButtonPushed, true);
            app.ANGULOGRANDEButton.Text = 'ANGULO GRANDE';
            app.WnLabel = uilabel(app.UIFigure);
            app.WnLabel.Text = 'Valor Wn';
            app.ValorWnTextArea = uitextarea(app.UIFigure);
            app.WnangulopqButton = uibutton(app.UIFigure, 'push');
            app.WnangulopqButton.ButtonPushedFcn = createCallbackFcn(app, @WnangulopqButtonPushed, true);
            app.WnangulopqButton.Text = 'Wn angulo pq';
            app.WnangulogrButton = uibutton(app.UIFigure, 'push');
            app.WnangulogrButton.Text = 'Wn angulo gr';
            app.ODEprButton = uibutton(app.UIFigure, 'push');
            app.ODEprButton.ButtonPushedFcn = createCallbackFcn(app, @ODEprButtonPushed, true);
            app.ODEprButton.Text = 'ODE pr';
            app.ODEgrButton = uibutton(app.UIFigure, 'push');
            app.ODEgrButton.ButtonPushedFcn = createCallbackFcn(app, @ODEgrButtonPushed, true);
            app.ODEgrButton.Text = 'ODE gr';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIPendulo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end